classdef appfunc_solv < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        Copyright2023curryqkaLabel     matlab.ui.control.Label
        visualresultiterModelButton    matlab.ui.control.Button
        SORButton                      matlab.ui.control.Button
        GaussSeidelButton              matlab.ui.control.Button
        JacobiButton                   matlab.ui.control.Button
        LossTextArea                   matlab.ui.control.TextArea
        LossTextAreaLabel              matlab.ui.control.Label
        visualresultButton             matlab.ui.control.Button
        SolutionTextArea               matlab.ui.control.TextArea
        SolutionTextAreaLabel          matlab.ui.control.Label
        AccuratesolutionTextArea       matlab.ui.control.TextArea
        AccuratesolutionTextAreaLabel  matlab.ui.control.Label
        ThedimensionofthematrixEditField  matlab.ui.control.EditField
        ThedimensionofthematrixEditFieldLabel  matlab.ui.control.Label
        condTextArea                   matlab.ui.control.TextArea
        condTextAreaLabel              matlab.ui.control.Label
        matrixTextArea                 matlab.ui.control.TextArea
        matrixTextAreaLabel            matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        Label                          matlab.ui.control.Label
        freegaussButton                matlab.ui.control.Button
        all_gauss_Button               matlab.ui.control.Button
        col_gauss_Button               matlab.ui.control.Button
        ordergaussButton               matlab.ui.control.Button
        calcondButton                  matlab.ui.control.Button
        setmatrixButton                matlab.ui.control.Button
        lossAxes                       matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: setmatrixButton
        function setmatrixButtonPushed(app, event)
            % num_matrix = 5;
            num_matrix = app.ThedimensionofthematrixEditField.Value;
            % add the function path 
            addpath("实验3.1\");
            [A,b] = set_equation(str2double(num_matrix));
            app.matrixTextArea.Value = mat2str([A,b]);
            % app.matrixTextArea.Value = num2str(1.5)
        end

        % Button pushed function: calcondButton
        function calcondButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            % disp(matrix);
            % add the function path
            addpath('实验3.1\');
            [X, cond_1, cond_2, cond_inf] = cal_cond(matrix(:,1:end-1),matrix(:,end));
            % 输出条件数
            app.condTextArea.Value = ['1-条件数:', num2str(cond_1),newline,...
                '2-条件数:', num2str(cond_2), newline, ...
                '无穷条件数:', num2str(cond_inf)];
            % 输出精确解
            app.AccuratesolutionTextArea.Value = mat2str(ones(length(X),1));
        end

        % Button pushed function: ordergaussButton
        function ordergaussButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = order_gauss(A,b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: col_gauss_Button
        function col_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = col_gauss(A,b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: all_gauss_Button
        function all_gauss_ButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = all_gauss(A, b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: freegaussButton
        function freegaussButtonPushed(app, event)
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            x = free_gauss(A, b);
            app.SolutionTextArea.Value = mat2str(x);
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            [loss1, ~, ~] = cal_loss(solution, x);
            % show the loss: 1-norm
            app.LossTextArea.Value = num2str(loss1);
        end

        % Button pushed function: visualresultButton
        function visualresultButtonPushed(app, event)
            save_loss = [];
            matrix = app.matrixTextArea.Value;
            matrix = eval(matrix{1});
            A = matrix(:, 1:end-1);
            b = matrix(:, end);
            % add the function path
            addpath('实验3.1\');
            % calculate the loss
            solution = app.AccuratesolutionTextArea.Value;
            solution = eval(solution{1});
            x = order_gauss(A, b);
            [loss1, ~, loss_inf] = cal_loss(solution, x);

            save_loss = [save_loss; loss_inf];

            x = col_gauss(A, b);
            [loss1, ~, loss_inf] = cal_loss(solution, x);

            save_loss = [save_loss; loss_inf];

            x = all_gauss(A, b);
            [loss1, ~, loss_inf] = cal_loss(solution, x);

            save_loss = [save_loss; loss_inf];

            x = free_gauss(A, b);
            [loss1, ~, loss_inf] = cal_loss(solution, x);

            save_loss = [save_loss; loss_inf];

            save_loss
            bar( 1:4, save_loss,'parent', app.lossAxes)
            
            app.lossAxes.XLabel.String = '方法';
            app.lossAxes.YLabel.String = '误差';

        end

        % Button pushed function: visualresultiterModelButton
        function visualresultiterModelButtonPushed(app, event)
            % test example : given dim, use class itermodel
            addpath('./实验3.3')
            dim = app.ThedimensionofthematrixEditField.Value;
          
            iterModel = itermodel(str2double(dim));
            %% matlab 要返回类才会更新
            iterModel = iterModel.set_matrix();
            
            %% 定义需要存储的数据
            save_loss_inf = [];
            save_x = [];
            %% LU分解
            [L, U] = iterModel.get_LU();
            [L, U] = iterModel.get_LU_check();
            disp('LU分解：')
            x = iterModel.gauss_method(L, U)
            [~, ~, loss_inf] = iterModel.cal_loss(x)
            
            save_loss_inf = [save_loss_inf; loss_inf];
            save_x = [save_x, x];
            
            %% J法
            rou_J = iterModel.cal_rou('J');
            % disp('jacobi：')
            [x, error_jacobi] = iterModel.jacobi_method();
            [~, ~, loss_inf] = iterModel.cal_loss(x);
            % save_loss_inf = [save_loss_inf; loss_inf];
            
            save_x = [save_x, x];
            
            %% GS法
            rou_GS = iterModel.cal_rou('G');
            % disp('高斯塞达尔：')
            [x, error_gauss_seidel] = iterModel.gs_method();
            [~, ~, loss_inf] = iterModel.cal_loss(x);
            
            save_loss_inf = [save_loss_inf; loss_inf];
            save_x = [save_x, x];
            %% SOR法
            rou_SOR = iterModel.cal_rou('S', 0.5);
            % disp('sor：')
            [x, error_sor] = iterModel.sor_method(0.5);
            [~, ~, loss_inf] = iterModel.cal_loss(x);
            % draw the loss fig
            if max(error_jacobi) > 100
                iterModel.get_losscurve(error_gauss_seidel, 'GS',error_sor, 'SOR')
            else
                iterModel.get_losscurve(error_jacobi, 'J', error_gauss_seidel, 'GS',error_sor,'SOR')
            end
            
            save_loss_inf = [save_loss_inf; loss_inf];
            save_x = [save_x, x];
            %% draw loss_inf
            
            lw = 1.5;
            t = 1:length(save_loss_inf);
            bar(t, save_loss_inf, 'parent', app.lossAxes, LineWidth=lw);
            hold on
            
            for i = 1:length(save_loss_inf)
                
                text(t(i),save_loss_inf(i),num2str(save_loss_inf(i),'%g%'),...
                'HorizontalAlignment','center',...
                'VerticalAlignment','bottom', 'Parent', app.lossAxes)
             
            end
            
            ax = app.lossAxes;
            ax.LineWidth = 1.5;
            ax.FontName = '微软雅黑';
            ax.FontSize = 14;
            grid on
            set(gca,'GridLineStyle','--')
            
            ax.XTick = 1:3;
            ax.XTickLabel = {'LU分解','GS法','SOR法'};
            % set(gca, 'XTick', 1:3, 'XTickLabel',{'LU分解','GS法','SOR法'})
            app.lossAxes.XLabel.String = '不同的方法';
            app.lossAxes.YLabel.String = '误差';
            % title('不同w的迭代法误差曲线');
            legend (ax, ['维度',num2str(dim)]);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.lossAxes = uiaxes(app.UIFigure);
            title(app.lossAxes, 'Title')
            xlabel(app.lossAxes, 'X')
            ylabel(app.lossAxes, 'Y')
            zlabel(app.lossAxes, 'Z')
            app.setmatrixButton = uibutton(app.UIFigure, 'push');
            app.setmatrixButton.ButtonPushedFcn = createCallbackFcn(app, @setmatrixButtonPushed, true);
            app.setmatrixButton.Text = 'set-matrix';
            app.calcondButton = uibutton(app.UIFigure, 'push');
            app.calcondButton.ButtonPushedFcn = createCallbackFcn(app, @calcondButtonPushed, true);
            app.calcondButton.Text = 'cal-cond';
            app.ordergaussButton = uibutton(app.UIFigure, 'push');
            app.ordergaussButton.ButtonPushedFcn = createCallbackFcn(app, @ordergaussButtonPushed, true);
            app.ordergaussButton.Text = 'order-gauss';
            app.col_gauss_Button = uibutton(app.UIFigure, 'push');
            app.col_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @col_gauss_ButtonPushed, true);
            app.col_gauss_Button.Text = 'col_gauss_Button';
            app.all_gauss_Button = uibutton(app.UIFigure, 'push');
            app.all_gauss_Button.ButtonPushedFcn = createCallbackFcn(app, @all_gauss_ButtonPushed, true);
            app.all_gauss_Button.Text = 'all_gauss_Button';
            app.freegaussButton = uibutton(app.UIFigure, 'push');
            app.freegaussButton.ButtonPushedFcn = createCallbackFcn(app, @freegaussButtonPushed, true);
            app.freegaussButton.Text = 'free-gauss';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '线性方程组求解器';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.matrixTextAreaLabel = uilabel(app.UIFigure);
            app.matrixTextAreaLabel.Text = 'matrix';
            app.matrixTextArea = uitextarea(app.UIFigure);
            app.condTextAreaLabel = uilabel(app.UIFigure);
            app.condTextAreaLabel.Text = 'cond';
            app.condTextArea = uitextarea(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel = uilabel(app.UIFigure);
            app.ThedimensionofthematrixEditFieldLabel.Text = 'The dimension of the matrix';
            app.ThedimensionofthematrixEditField = uieditfield(app.UIFigure, 'text');
            app.AccuratesolutionTextAreaLabel = uilabel(app.UIFigure);
            app.AccuratesolutionTextAreaLabel.Text = 'Accurate solution';
            app.AccuratesolutionTextArea = uitextarea(app.UIFigure);
            app.SolutionTextAreaLabel = uilabel(app.UIFigure);
            app.SolutionTextAreaLabel.Text = ' Solution';
            app.SolutionTextArea = uitextarea(app.UIFigure);
            app.visualresultButton = uibutton(app.UIFigure, 'push');
            app.visualresultButton.ButtonPushedFcn = createCallbackFcn(app, @visualresultButtonPushed, true);
            app.visualresultButton.Text = 'visual-result';
            app.LossTextAreaLabel = uilabel(app.UIFigure);
            app.LossTextAreaLabel.Text = 'Loss';
            app.LossTextArea = uitextarea(app.UIFigure);
            app.JacobiButton = uibutton(app.UIFigure, 'push');
            app.JacobiButton.Text = 'Jacobi';
            app.GaussSeidelButton = uibutton(app.UIFigure, 'push');
            app.GaussSeidelButton.Text = 'Gauss-Seidel';
            app.SORButton = uibutton(app.UIFigure, 'push');
            app.SORButton.Text = 'SOR';
            app.visualresultiterModelButton = uibutton(app.UIFigure, 'push');
            app.visualresultiterModelButton.ButtonPushedFcn = createCallbackFcn(app, @visualresultiterModelButtonPushed, true);
            app.visualresultiterModelButton.Text = 'visual-result-iterModel';
            app.Copyright2023curryqkaLabel = uilabel(app.UIFigure);
            app.Copyright2023curryqkaLabel.Text = 'Copyright © 2023 curryqka ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appfunc_solv

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end